classdef LSS_App < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        DoFArmControlUIFigure      matlab.ui.Figure
        CONNECTButton              matlab.ui.control.Button
        STOPButton_2               matlab.ui.control.Button
        VIEWButton                 matlab.ui.control.Button
        SENDButton                 matlab.ui.control.Button
        UPDATEButton_2             matlab.ui.control.Button
        LEDDropDown                matlab.ui.control.DropDown
        LEDDropDownLabel           matlab.ui.control.Label
        DOFRoboticArmControlLabel  matlab.ui.control.Label
        COMDropDown                matlab.ui.control.DropDown
        COMDropDownLabel           matlab.ui.control.Label
        BAUDDropDown               matlab.ui.control.DropDown
        BAUDDropDownLabel          matlab.ui.control.Label
        LIMPButton                 matlab.ui.control.Button
        ForwardKinematicsPanel     matlab.ui.container.Panel
        GripperEditField           matlab.ui.control.NumericEditField
        GripperEditFieldLabel      matlab.ui.control.Label
        WristEditField             matlab.ui.control.NumericEditField
        WristEditFieldLabel        matlab.ui.control.Label
        ElbowEditField             matlab.ui.control.NumericEditField
        ElbowEditFieldLabel        matlab.ui.control.Label
        ShoulderEditField          matlab.ui.control.NumericEditField
        ShoulderEditFieldLabel     matlab.ui.control.Label
        BaseEditField              matlab.ui.control.NumericEditField
        BaseEditFieldLabel         matlab.ui.control.Label
        InverseKinematicsPanel     matlab.ui.container.Panel
        YinSlider                  matlab.ui.control.Slider
        YinSliderLabel             matlab.ui.control.Label
        GSlider                    matlab.ui.control.Slider
        GSliderLabel               matlab.ui.control.Label
        ZinSlider                  matlab.ui.control.Slider
        ZinSliderLabel             matlab.ui.control.Label
        XinSlider                  matlab.ui.control.Slider
        XinSliderLabel             matlab.ui.control.Label
        ArmAxes                    matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        serialConnection  % stores the serial connection
        serialObj % Serial object for ESP32 connection
        jointAngles =[90 90 90 0]  % stores the current joint angle [q1 , q2 , q3 . q4]
        L1 = 100 % Link 1 length in mm 
        L2 = 65 % Link 2 length in mm 
        L3 = 50;    % Wrist to gripper tip 
        armPlot % Handle for arm plot
    end
    
    methods (Access = private)
        
        function A = DH_TRANSFORM(~, a, alpha_deg, d, theta_deg)
            alpha = deg2rad(alpha_deg);
            theta = deg2rad(theta_deg);
            
            A = [cos(theta), -sin(theta)*cos(alpha), sin(theta)*sin(alpha), a*cos(theta);
                 sin(theta), cos(theta)*cos(alpha), -cos(theta)*sin(alpha), a*sin(theta);
                 0, sin(alpha), cos(alpha), d;
                 0, 0, 0, 1];
        end
        
        function [T_05, pos] = FKINE(app, theta_1, theta_2, theta_3, theta_4)
                 A1 = app.DH_TRANSFORM(0, 90, 0, theta_1);
                 A2 = app.DH_TRANSFORM(app.L1, 0, 0, theta_2);
                 A3 = app.DH_TRANSFORM(app.L2, 0, 0, theta_3);
                 A4 = app.DH_TRANSFORM(app.L3, 0, 0, theta_4);  % Gripper orientation

                T_05 = A1 * A2 * A3 * A4;
                pos = T_05(1:3, 4);  % End-effector position
        end
        
        function [theta1, theta2, theta3, success] = IKINE(app, x_in, y_in, z_in)
    % Convert inches to mm
                 x = x_in * 25.4;
                 y = y_in * 25.4;
                 z = z_in * 25.4;

                 app.L1 = app.L1;
                 app.L2 = app.L2;

                 r = sqrt(x^2 + y^2);
                 s = z;

    % θ1: Base rotation
                 theta1 = atan2(y, x);

    % Law of cosines for θ3
                 D = (r^2 + s^2 - app.L1^2 - app.L2^2) / (2 * app.L1 * app.L2);

    % Clamp D between -1 and 1
                 D = max(min(D, 1), -1);

    % Check reachability
                 if abs(D) > 1
                    success = false;
                    theta1 = 0; theta2 = 0; theta3 = 0;
                    return;
                 end

                 theta3 = atan2(sqrt(1 - D^2), D);  % elbow angle
                 K1 = app.L1 + app.L2 * cos(theta3);
                 K2 = app.L2 * sin(theta3);
                 theta2 = atan2(s, r) - atan2(K2, K1);

    % Convert to degrees
                 theta1 = rad2deg(theta1);
                 theta2 = rad2deg(theta2);
                 theta3 = rad2deg(theta3);

                 success = true;
        end
                       
        function updateFKDisplay(app)
                 [~, pos] = app.FKINE(app.jointAngles(1), app.jointAngles(2), app.jointAngles(3), app.jointAngles(4));
                 pos_in = pos / 25.4;  % Convert mm → inches

    % Clamp values to slider limits
                 app.XinSlider.Value = max(min(pos_in(1), app.XinSlider.Limits(2)), app.XinSlider.Limits(1));
                 app.YinSlider.Value = max(min(pos_in(2), app.YinSlider.Limits(2)), app.YinSlider.Limits(1));
                 app.ZinSlider.Value = max(min(pos_in(3), app.ZinSlider.Limits(2)), app.ZinSlider.Limits(1));
        end
        
        
        function valid = validateWorkspace(app, x, y, z)
    % Convert to mm
              x_mm = x * 25.4;
              y_mm = y * 25.4;
              z_mm = z * 25.4;
    
              r = sqrt(x_mm^2 + y_mm^2);
              s = z_mm;
              current_reach = sqrt(r^2 + s^2);
    
              max_reach = app.L1 + app.L2;  % 165mm
              min_height = 0;               % Ground level
    
    % Check if within reachable cylinder
               valid = (current_reach <= max_reach) && ...
               (z_mm >= min_height) && ...
               (z_mm <= (app.L1 + app.L2)); % Max height
            
        end
        
        function plotArm(app)
                q = app.jointAngles;  % q = [q1, q2, q3, q4]

    % DH Transforms
                A1 = app.DH_TRANSFORM(0, 90, 0, q(1));
                A2 = app.DH_TRANSFORM(app.L1, 0, 0, q(2));
                A3 = app.DH_TRANSFORM(app.L2, 0, 0, q(3));
                A4 = app.DH_TRANSFORM(app.L3, 0, 0, q(4));  % Gripper

    % Homogeneous Transforms
                T0 = eye(4);
                T1 = T0 * A1;
                T2 = T1 * A2;
                T3 = T2 * A3;
                T4 = T3 * A4;

    % Extract positions (in mm)
                p0 = T0(1:3, 4);
                p1 = T1(1:3, 4);
                p2 = T2(1:3, 4);
                p3 = T3(1:3, 4);
                p4 = T4(1:3, 4);

    % Combine coordinates for plotting
                X = [p0(1), p1(1), p2(1), p3(1), p4(1)];
                Y = [p0(2), p1(2), p2(2), p3(2), p4(2)];
                Z = [p0(3), p1(3), p2(3), p3(3), p4(3)];

    % Plot
                cla(app.ArmAxes);
                plot3(app.ArmAxes, X, Y, Z, '-o', 'LineWidth', 2);
                hold(app.ArmAxes, 'on');
                plot3(app.ArmAxes, p4(1), p4(2), p4(3), 'rs', 'MarkerSize', 8, 'MarkerFaceColor', 'r');
                hold(app.ArmAxes, 'off');

    % Labels and formatting
                xlabel(app.ArmAxes, 'X (mm)');
                ylabel(app.ArmAxes, 'Y (mm)');
                zlabel(app.ArmAxes, 'Z (mm)');
                title(app.ArmAxes, '4-DOF Robotic Arm');
                grid(app.ArmAxes, 'on');
                axis(app.ArmAxes, 'equal');
                xlim(app.ArmAxes, [-250, 250]);
                ylim(app.ArmAxes, [-250, 250]);
                zlim(app.ArmAxes, [-50, 250]);
                view(app.ArmAxes, [135 30]);
        end
        
        function XinSliderValueChanged(app, ~)
            
            x = app.XinSlider.Value;
            y = app.YinSlider.Value;
            z = app.ZinSlider.Value;

            [theta1, theta2, theta3, success] = app.IKINE(x, y, z);

            if success
               app.jointAngles = [theta1, theta2, theta3];
               app.BaseEditField.Value = theta1;
               app.ShoulderEditField.Value = theta2;
               app.ElbowEditField.Value = theta3;
               app.plotArm();
            else
            msg = sprintf("Target position out of reach!\nX: %.1f in, Y: %.1f in, Z: %.1f in\nTry values within:\nX: [%d to %d] in\nY: [%d to %d] in\nZ: [%d to %d] in", ...
                x, y, z, ...
                app.XinSlider.Limits(1), app.XinSlider.Limits(2), ...
                app.YinSlider.Limits(1), app.YinSlider.Limits(2), ...
                app.ZinSlider.Limits(1), app.ZinSlider.Limits(2));
                uialert(app.DoFArmControlUIFigure, msg, 'IK Error');
            end
        end
    end  

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
             app.ZinSlider.Limits = [0, 8];
             app.ZinSlider.Value = 2;  
            % Initialize serial COM dropdown
              ports = serialportlist("all");
              if isempty(ports)
                 app.COMDropDown.Items = {'OFF'};
              else
                 app.COMDropDown.Items = ports;
                 app.COMDropDown.Value = ports(1);
              end
              app.BAUDDropDown.Items = {'9600', '57600', '115200'};
              app.BAUDDropDown.Value = '115200';
              app.LEDDropDown.Items = {'OFF', 'ON'};
              app.LEDDropDown.Value = 'OFF';
  % Link lengths (correct from document)
              app.L1 = 100;
              app.L2 = 65;
              app.L3 = 50;    
    % Initial joint angles
              app.jointAngles = [90, 90, 90, 0];  % [Base, Shoulder, Elbow, Gripper]
    
    % Plot initial arm position
              app.plotArm();
        end

        % Button pushed function: SENDButton
        function SENDButtonPushed(app, event)
         % Check if serial port object exists and is valid
         if ~isempty(app.s) && isvalid(app.s)
              try
            % Construct command string from slider values or variables
                 baseAngle = app.BaseSlider.Value;
                 shoulderAngle = app.ShoulderSlider.Value;
                 elbowAngle = app.ElbowSlider.Value;
                 gripperAngle = app.GripperSlider.Value;

                 cmd = sprintf('B%d,S%d,E%d,G%d', round(baseAngle), round(shoulderAngle), round(elbowAngle), round(gripperAngle));
            
                 writeline(app.s, cmd);   % Send command to ESP32
            
                 disp(['Sent: ', cmd]);
            
            % Optional: Read response (non-blocking)
            % response = readline(app.s);
            % disp(['Received: ', response]);
            
              catch ME
                    uialert(app.UIFigure, ['Failed to send data: ', ME.message], 'Send Error');
              end
         else
             uialert(app.UIFigure,'Serial port not connected','Send Error');
         end
        end

        % Button pushed function: VIEWButton
        function VIEWButtonPushed(app, event)
             % Get target position from sliders
                 x = app.XinSlider.Value;
                 y = app.YinSlider.Value;
                 z = app.ZinSlider.Value;
                 g = app.GSlider.Value;  % Gripper angle input

    % Run IK
                [theta1, theta2, theta3, success] = app.IKINE(x, y, z);

                if success
        %Update all joint angles including gripper
                   
                 app.jointAngles = [theta1, theta2, theta3, g];

        %Update GUI fields for all joints
                 app.BaseEditField.Value = theta1;
                 app.ShoulderEditField.Value = theta2;
                 app.ElbowEditField.Value = theta3;
                 app.GripperEditField.Value = g;

        %Replot updated pose
                 app.plotArm();
                 else
                  msg = sprintf("Target position out of reach!\nX: %.1f in\nY: %.1f in\nZ: %.1f in", x, y, z);
                  uialert(app.DoFArmControlUIFigure, msg, 'IK Error');
                end

        end

        % Button pushed function: LIMPButton
        function LIMPButtonPushed(app, event)
           pause(2);
            if app.COMDropDown.Value == "ON"
                fprintf(app.serialConnection, sprintf('#%d%s', 254, 'L'));
            else
                helpdlg({'COM port is OFF',...
                         'Please turn it ON'});
            end
        end

        % Value changed function: BAUDDropDown
        function BAUDDropDownValueChanged(app, event)
           if app.COMDropDown.Value == "ON"
                fprintf(app.serialConnection, sprintf('#%d%s%d', 254, 'CB', str2double(app.BAUDDropDown.Value)));
                fprintf(app.serialConnection, sprintf('#%d%s', 254, 'RESET'));
                pause(2);
                fprintf(app.serialConnection, sprintf('#%d%s', 254, 'CONFIRM'));
                delete(instrfind);
                app.serialConnection = serial(serialportlist);
                app.serialConnection.Baudrate = str2double(app.BAUDDropDown.Value);
                app.serialConnection.Terminator = 'CR';
                fopen(app.serialConnection);
            else
                helpdlg({'COM port is OFF',...
                         'Please turn it ON'});
            end
        end

        % Value changed function: COMDropDown
        function COMDropDownValueChanged(app, event)
           
           if app.COMDropDown.Value == "ON"
                if isempty(serialportlist)
                    helpdlg({'LSS arm is not connected',...
                             'Please connect the arm to the PC'});
                    app.COMDropDown.Value = "OFF";
                else
                    delete(instrfind);
                    app.serialConnection = serial(serialportlist);
                    app.serialConnection.Baudrate = str2double(app.BAUDDropDown.Value);
                    app.serialConnection.Terminator = 'CR';
                    fopen(app.serialConnection);
                end
           end
        end

        % Close request function: DoFArmControlUIFigure
        function DoFArmControlUIFigureCloseRequest(app, event)
                 if ~isempty(serialportlist)
                     fclose(app.serialConnection);
                    delete(instrfind);
                end
                delete(app);
        end

        % Value changed function: LEDDropDown
        function LEDDropDownValueChanged(app, event)
            LED = app.LEDDropDown.Value;
            if app.COMDropDown.Value == "ON"
                switch LED
                    case "Black"
                        fprintf(app.serialConnection, sprintf('#%d%s%d', 254, 'LED', 0));
                    case "Red"
                        fprintf(app.serialConnection, sprintf('#%d%s%d', 254, 'LED', 1));
                    case "Green"
                        fprintf(app.serialConnection, sprintf('#%d%s%d', 254, 'LED', 2));
                    case "Blue"
                        fprintf(app.serialConnection, sprintf('#%d%s%d', 254, 'LED', 3));
                    case "Yellow"
                        fprintf(app.serialConnection, sprintf('#%d%s%d', 254, 'LED', 4));
                    case "Cyan"
                        fprintf(app.serialConnection, sprintf('#%d%s%d', 254, 'LED', 5));
                    case "Magenta"
                        fprintf(app.serialConnection, sprintf('#%d%s%d', 254, 'LED', 6));
                    case "White"
                        fprintf(app.serialConnection, sprintf('#%d%s%d', 254, 'LED', 7));
                end
            else
                helpdlg({'COM port is OFF',...
                         'Please turn it ON'});
            end
        end

        % Button pushed function: UPDATEButton_2
        function UPDATEButton_2Pushed(app, event)
             q1 = app.BaseEditField.Value;
    q2 = app.ShoulderEditField.Value;
    q3 = app.ElbowEditField.Value;
    q4 = app.GripperEditField.Value;

    % Update internal joint angles
    app.jointAngles = [q1, q2, q3, q4];

    % Update IK sliders from FK
    app.updateFKDisplay();

    % Update plot
    app.plotArm();
        end

        % Button pushed function: STOPButton_2
        function STOPButtonPushed(app, event)
           try
              port = app.COMDropDown.Value;
              baud = str2double(app.BAUDDropDown.Value);
              if ~isempty(port)
                  s = serialport(port, baud);
                  writeline(s, 'STOP');
                  clear s;
              end
              catch
              % Fail silently
           end
        end

        % Button pushed function: CONNECTButton
        function CONNECTButtonPushed(app, event)
                 port = app.COMDropDown.Value;        % e.g. "COM5"
                 baud = str2double(app.BaudRateDropDown.Value); % e.g. 115200
                 try
                 app.s = serialport(port, baud);
                 disp('Connected to ESP32');
                 catch
                 uialert(app.UIFigure,'Failed to connect to serial port','Connection Error');
                 end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.DoFArmControlUIFigure = uifigure('Visible', 'off');
            app.DoFArmControlUIFigure.Name = '4 DoF Arm Control';
            app.DoFArmControlUIFigure.CloseRequestFcn = createCallbackFcn(app, @DoFArmControlUIFigureCloseRequest, true);
            app.ArmAxes = uiaxes(app.DoFArmControlUIFigure);
            title(app.ArmAxes, 'Arm Axes')
            xlabel(app.ArmAxes, 'X')
            ylabel(app.ArmAxes, 'Y')
            zlabel(app.ArmAxes, 'Z')
            app.InverseKinematicsPanel = uipanel(app.DoFArmControlUIFigure);
            app.InverseKinematicsPanel.Title = 'Inverse Kinematics';
            app.XinSliderLabel = uilabel(app.InverseKinematicsPanel);
            app.XinSliderLabel.Text = 'X (in)';
            app.XinSlider = uislider(app.InverseKinematicsPanel);
            app.XinSlider.Limits = [-17 17];
            app.XinSlider.MajorTicks = [-17 -12 -8 -4 0 4 8 12 17];
            app.XinSlider.MinorTicks = [-17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17];
            app.ZinSliderLabel = uilabel(app.InverseKinematicsPanel);
            app.ZinSliderLabel.Text = 'Z (in)';
            app.ZinSlider = uislider(app.InverseKinematicsPanel);
            app.ZinSlider.Limits = [1 22];
            app.ZinSlider.Value = 1;
            app.GSliderLabel = uilabel(app.InverseKinematicsPanel);
            app.GSliderLabel.Text = 'G (°)';
            app.GSlider = uislider(app.InverseKinematicsPanel);
            app.GSlider.Limits = [0 90];
            app.GSlider.MajorTicks = [0 15 30 45 60 75 90];
            app.YinSliderLabel = uilabel(app.InverseKinematicsPanel);
            app.YinSliderLabel.Text = 'Y (in)';
            app.YinSlider = uislider(app.InverseKinematicsPanel);
            app.YinSlider.Limits = [-17 17];
            app.YinSlider.MajorTicks = [-17 -12 -8 -4 0 4 8 12 17];
            app.YinSlider.MinorTicks = [-17 -16 -15 -14 -13 -12 -11 -10 -9 -8 -7 -6 -5 -4 -3 -2 -1 0 1 2 3 4 5 6 7 8 9 10 11 12 13 14 15 16 17];
            app.ForwardKinematicsPanel = uipanel(app.DoFArmControlUIFigure);
            app.ForwardKinematicsPanel.Title = 'Forward Kinematics';
            app.BaseEditFieldLabel = uilabel(app.ForwardKinematicsPanel);
            app.BaseEditFieldLabel.Text = 'Base (°)';
            app.BaseEditField = uieditfield(app.ForwardKinematicsPanel, 'numeric');
            app.BaseEditField.Limits = [-180 180];
            app.BaseEditField.Editable = 'off';
            app.ShoulderEditFieldLabel = uilabel(app.ForwardKinematicsPanel);
            app.ShoulderEditFieldLabel.Text = 'Shoulder (°)';
            app.ShoulderEditField = uieditfield(app.ForwardKinematicsPanel, 'numeric');
            app.ShoulderEditField.Limits = [-90 90];
            app.ShoulderEditField.Editable = 'off';
            app.ElbowEditFieldLabel = uilabel(app.ForwardKinematicsPanel);
            app.ElbowEditFieldLabel.Text = 'Elbow (°)';
            app.ElbowEditField = uieditfield(app.ForwardKinematicsPanel, 'numeric');
            app.ElbowEditField.Limits = [-90 90];
            app.ElbowEditField.Editable = 'off';
            app.WristEditFieldLabel = uilabel(app.ForwardKinematicsPanel);
            app.WristEditFieldLabel.Text = 'Wrist (°)';
            app.WristEditField = uieditfield(app.ForwardKinematicsPanel, 'numeric');
            app.WristEditField.Limits = [-90 90];
            app.WristEditField.Editable = 'off';
            app.GripperEditFieldLabel = uilabel(app.ForwardKinematicsPanel);
            app.GripperEditFieldLabel.Text = 'Gripper (°)';
            app.GripperEditField = uieditfield(app.ForwardKinematicsPanel, 'numeric');
            app.GripperEditField.Limits = [0 90];
            app.GripperEditField.Editable = 'off';
            app.LIMPButton = uibutton(app.DoFArmControlUIFigure, 'push');
            app.LIMPButton.ButtonPushedFcn = createCallbackFcn(app, @LIMPButtonPushed, true);
            app.LIMPButton.Text = 'LIMP';
            app.BAUDDropDownLabel = uilabel(app.DoFArmControlUIFigure);
            app.BAUDDropDownLabel.Text = 'BAUD';
            app.BAUDDropDown = uidropdown(app.DoFArmControlUIFigure);
            app.BAUDDropDown.Items = {'2400', '9600', '38400', '115200'};
            app.BAUDDropDown.ValueChangedFcn = createCallbackFcn(app, @BAUDDropDownValueChanged, true);
            app.BAUDDropDown.Value = '115200';
            app.COMDropDownLabel = uilabel(app.DoFArmControlUIFigure);
            app.COMDropDownLabel.Text = 'COM';
            app.COMDropDown = uidropdown(app.DoFArmControlUIFigure);
            app.COMDropDown.Items = {'ON', 'OFF'};
            app.COMDropDown.ValueChangedFcn = createCallbackFcn(app, @COMDropDownValueChanged, true);
            app.COMDropDown.Value = 'ON';
            app.DOFRoboticArmControlLabel = uilabel(app.DoFArmControlUIFigure);
            app.DOFRoboticArmControlLabel.Text = '4 DOF Robotic Arm Control';
            app.LEDDropDownLabel = uilabel(app.DoFArmControlUIFigure);
            app.LEDDropDownLabel.Text = 'LED';
            app.LEDDropDown = uidropdown(app.DoFArmControlUIFigure);
            app.LEDDropDown.Items = {'OFF', 'Red', 'Green', 'Blue', 'Yellow', 'Cyan', 'Magenta', 'White'};
            app.LEDDropDown.ValueChangedFcn = createCallbackFcn(app, @LEDDropDownValueChanged, true);
            app.LEDDropDown.Value = 'OFF';
            app.UPDATEButton_2 = uibutton(app.DoFArmControlUIFigure, 'push');
            app.UPDATEButton_2.ButtonPushedFcn = createCallbackFcn(app, @UPDATEButton_2Pushed, true);
            app.UPDATEButton_2.Text = 'UPDATE';
            app.SENDButton = uibutton(app.DoFArmControlUIFigure, 'push');
            app.SENDButton.ButtonPushedFcn = createCallbackFcn(app, @SENDButtonPushed, true);
            app.SENDButton.Text = 'SEND';
            app.VIEWButton = uibutton(app.DoFArmControlUIFigure, 'push');
            app.VIEWButton.ButtonPushedFcn = createCallbackFcn(app, @VIEWButtonPushed, true);
            app.VIEWButton.Text = 'VIEW';
            app.STOPButton_2 = uibutton(app.DoFArmControlUIFigure, 'push');
            app.STOPButton_2.ButtonPushedFcn = createCallbackFcn(app, @STOPButtonPushed, true);
            app.STOPButton_2.Text = 'STOP';
            app.CONNECTButton = uibutton(app.DoFArmControlUIFigure, 'push');
            app.CONNECTButton.ButtonPushedFcn = createCallbackFcn(app, @CONNECTButtonPushed, true);
            app.CONNECTButton.Text = 'CONNECT';
            % Show the figure after all components are created
            app.DoFArmControlUIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LSS_App

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.DoFArmControlUIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.DoFArmControlUIFigure)
        end
    end
end